classdef Calibrazione_EMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SAVEButton                   matlab.ui.control.Button
        NAMEEditField                matlab.ui.control.EditField
        NAMEEditFieldLabel           matlab.ui.control.Label
        SIMULATIONTIMETextArea       matlab.ui.control.TextArea
        SIMULATIONTIMETextAreaLabel  matlab.ui.control.Label
        Threshold2FastTextArea       matlab.ui.control.TextArea
        Threshold2FastTextAreaLabel  matlab.ui.control.Label
        Threshold1FastTextArea       matlab.ui.control.TextArea
        Threshold1FastTextAreaLabel  matlab.ui.control.Label
        Threshold2SlowTextArea       matlab.ui.control.TextArea
        Threshold2SlowTextAreaLabel  matlab.ui.control.Label
        Threshold1SlowTextArea       matlab.ui.control.TextArea
        Threshold1SlowTextAreaLabel  matlab.ui.control.Label
        CCThresholdlowTextArea       matlab.ui.control.TextArea
        CCThresholdlowLabel          matlab.ui.control.Label
        INITIALIZEButton             matlab.ui.control.Button
        CCThresholdhighTextArea      matlab.ui.control.TextArea
        CCThresholdhighLabel         matlab.ui.control.Label
        RESETButton                  matlab.ui.control.Button
        Threshold2lowTextArea        matlab.ui.control.TextArea
        Threshold2lowTextAreaLabel   matlab.ui.control.Label
        Threshold2highTextArea       matlab.ui.control.TextArea
        Threshold2highTextAreaLabel  matlab.ui.control.Label
        Threshold1lowTextArea        matlab.ui.control.TextArea
        Threshold1lowTextAreaLabel   matlab.ui.control.Label
        Threshold1highTextArea       matlab.ui.control.TextArea
        Threshold1highTextAreaLabel  matlab.ui.control.Label
        STARTSIMULATIONButton        matlab.ui.control.Button
        SimulationButtonGroup        matlab.ui.container.ButtonGroup
        FastOpenButton               matlab.ui.control.RadioButton
        SlowOpenButton               matlab.ui.control.RadioButton
        FastCloseButton              matlab.ui.control.RadioButton
        SlowCloseButton              matlab.ui.control.RadioButton
        CocontractionButton          matlab.ui.control.RadioButton
        CloseButton                  matlab.ui.control.RadioButton
        OpenButton                   matlab.ui.control.RadioButton
        RestButton                   matlab.ui.control.RadioButton
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

         HH1 = []; % plot handles
         HH2 = []; % plot handles
         e1_History = []; % Storico del segnale e1
         e2_History = []; % Storico del segnale e2
         time_History = []; % Asse dei tempi
         startTime % Tempo iniziale della simulazione
         simulinkModel = 'Sim_app'; % Nome del modello Simulink                
         simulationDuration = 20; % Durata della simulazione in secondi
         updateTimer
         
    end

    methods (Access = private)

        function updateLiveData(app) % Live update during simulation
        try
            % Time spent from start of simulation
            elapsedTime = toc(app.startTime);
            
            remainingTime = app.simulationDuration - elapsedTime; %tempo calcolato come countdown

            if remainingTime < 0
                remainingTime = 0;
            end

            app.SIMULATIONTIMETextArea.Value = {sprintf('%.2f s', remainingTime) };

            % if remainingTime == 0
            %     % Stops timer
            %     if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
            %         stop(app.updateTimer);
            %         delete(app.updateTimer);
            %         app.updateTimer = [];
            %     end
            % 
            %     cla(app.UIAxes);
            % end
            
            if remainingTime == 0
                stopSimulation(app);  % Richiama la funzione che ferma la simulazione e pulisce il plot
            end

            % Read variables from workspace
            if evalin('base', 'exist(''e1_Live'', ''var'')') && evalin('base', 'exist(''e2_Live'', ''var'')') %&& evalin('base', 'exist(''Time_Live'', ''var'')') 
                e1 = evalin('base', 'e1_Live');
                e2 = evalin('base', 'e2_Live');
                %Time_live = evalin('base', 'Time_Live');
            else
                return; % Exit if the variables aren't available yet
            end

            % Accumulate data for plot and post-simulation analysis
            app.time_History(end+1) = elapsedTime;%  Time_live;
            app.e1_History(end+1)   = e1;
            app.e2_History(end+1)   = e2;

            % 
            % app.time_History = evalin('base', 'Time_live');
            % app.e1_History   = evalin('base', 'e1_Live');
            % app.e2_History   = evalin('base', 'e2_Live');

            % if length(e1_Live) < length(Time_live)
            %        e1_live(end+1) = Nan;
            % end
            % if length(e2_Live) < length(Time_live)
            %        e2_live(end+1) = Nan;
            % end
            
            % Update Plot
            delete(app.HH1) 
            delete(app.HH2)
            app.HH1 = plot(app.UIAxes, app.time_History, app.e1_History, 'r', 'LineWidth', 1.5);
            % hold(app.UIAxes, 'on');
            app.HH2 = plot(app.UIAxes, app.time_History, app.e2_History, 'b', 'LineWidth', 1.5);
            legend(app.UIAxes, {'e1', 'e2'});

            % hold(app.UIAxes, 'off');
            % xlabel(app.UIAxes, 'Time (s)');
            % ylabel(app.UIAxes, 'Signals');
            % title(app.UIAxes, 'Real time EMG signals');
            
            % grid(app.UIAxes, 'on');
            
            % Check if simulation is finished
            if strcmp(get_param(app.simulinkModel, 'SimulationStatus'), 'stopped')
                % Stop and delete 
                % stop(app.updateTimer);
                % delete(app.updateTimer);
                % app.updateTimer = [];
                % 
                % Post simulation analysis using the accumulated data
                postSimulationAnalysis(app);
            end
            
        catch ME
            disp('Errore in updateLiveData:');
            disp(ME.message);
        end
    end

    
function startSimulation(app) %Function to start simulation in asynchronous mode
    %If a valid timer exists, stop and delete it
   
    if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
        if strcmp(app.updateTimer.Running, 'on')
            stop(app.updateTimer);
        end
        delete(app.updateTimer);
        app.updateTimer = [];
    end
    
    % Initialize the data array and the timer 
    app.e1_History = [];
    app.e2_History = [];
    app.time_History = [];
    app.startTime = tic;
    
    % Create new timer for live update
    app.updateTimer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', 0.05, ...       % Aggiornamento ogni 0.2 s
                              'TimerFcn', @(~,~) updateLiveData(app));
    
    % Start simulation in asynchronous mode
    set_param(app.simulinkModel, 'SimulationMode', 'normal');
    set_param(app.simulinkModel, 'SimulationCommand', 'start');
    
    % Start the timer
    start(app.updateTimer);
end


function postSimulationAnalysis(app) %% Post simulation analysis

    % Use the accumulated data((e1_History, e2_History) to perform analysis according to the selected mode
    selected_option = app.SimulationButtonGroup.SelectedObject.Text;

    switch selected_option
        case 'Rest'

            e1_low_per = prctile(app.e1_History, 95);
            e2_low_per = prctile(app.e2_History, 95);
            app.Threshold1lowTextArea.Value = num2str(e1_low_per);
            app.Threshold2lowTextArea.Value = num2str(e2_low_per);
            assignin('base', 'T1_low', e1_low_per);
            assignin('base', 'T2_low', e2_low_per);

        case 'Close'

            e1_high_per = prctile(app.e1_History, 95);
            app.Threshold1highTextArea.Value = num2str(e1_high_per);
            assignin('base', 'T1_high', e1_high_per);

        case 'Open'

            e2_high_per = prctile(app.e2_History, 95);
            app.Threshold2highTextArea.Value = num2str(e2_high_per);
            assignin('base', 'T2_high', e2_high_per);

        case 'Co-contraction'

            if evalin('base', 'exist(''out'', ''var'')')
                outObj = evalin('base', 'out');
                try
                    em_ts = outObj.em;
                    if isa(em_ts, 'timeseries')
                        em_data = em_ts.Data;
                        em_per_h = prctile(em_data, 50);
                        em_per_l = prctile(em_data, 5);
                        app.CCThresholdhighTextArea.Value = num2str(em_per_h);
                        app.CCThresholdlowTextArea.Value = num2str(em_per_l);
                    else
                        disp('em non è un timeseries.');
                    end
                catch ME
                    disp(['Errore nell''accesso a em: ' ME.message]);
                end
            else
                disp('Variabile out non trovata nel workspace.');
            end

            % em = (app.e1_History + app.e2_History) / 2;
            % em_per_h = prctile(em, 50);
            % em_per_l = prctile(em, 5);
            % app.CCThresholdhighTextArea.Value = num2str(em_per_h);
            % app.CCThresholdlowTextArea.Value = num2str(em_per_l);

        case 'Slow Close'
            if evalin('base', 'exist(''out'', ''var'')')
                outObj = evalin('base', 'out');
                try
                    % Prova ad accedere dinamicamente al segnale v1
                    v1_ts = outObj.v1;
                    if isa(v1_ts, 'timeseries')
                        v1_data = v1_ts.Data;
                        T1_slow = prctile(v1_data, 95);
                        app.Threshold1SlowTextArea.Value = num2str(T1_slow);
                        assignin('base', 'T1_slow', T1_slow);
                    else
                        disp('v1 non è un timeseries.');
                    end
                catch ME
                    disp(['Errore nell''accesso a v1: ' ME.message]);
                end
            else
                disp('Variabile out non trovata nel workspace.');
            end

            
        case 'Fast Close'
            if evalin('base', 'exist(''out'', ''var'')')
                outObj = evalin('base', 'out');
                try
                    v1_ts = outObj.v1;
                    if isa(v1_ts, 'timeseries')
                        v1_data = v1_ts.Data;
                        T1_fast = prctile(v1_data, 95);
                        app.Threshold1FastTextArea.Value = num2str(T1_fast);
                        assignin('base', 'T1_fast', T1_fast);
                    else
                        disp('v1 non è un timeseries.');
                    end
                catch ME
                    disp(['Errore nell''accesso a v1: ' ME.message]);
                end
            else
                disp('Variabile out non trovata nel workspace.');
            end


        case 'Slow Open'
            if evalin('base', 'exist(''out'', ''var'')')
                outObj = evalin('base', 'out');
                try
                    v2_ts = outObj.v2;
                    if isa(v2_ts, 'timeseries')
                        v2_data = v2_ts.Data;
                        T2_slow = prctile(v2_data, 95);
                        app.Threshold2SlowTextArea.Value = num2str(T2_slow);
                        assignin('base', 'T2_slow', T2_slow);
                    else
                        disp('v2 non è un timeseries.');
                    end
                catch ME
                    disp(['Errore nell''accesso a v2: ' ME.message]);
                end
            else
                disp('Variabile out non trovata nel workspace.');
            end


        case 'Fast Open'
            if evalin('base', 'exist(''out'', ''var'')')
                outObj = evalin('base', 'out');
                try
                    v2_ts = outObj.v2;
                    if isa(v2_ts, 'timeseries')
                        v2_data = v2_ts.Data;
                        T2_fast = prctile(v2_data, 95);
                        app.Threshold2FastTextArea.Value = num2str(T2_fast);
                        assignin('base', 'T2_fast', T2_fast);
                    else
                        disp('v2 non è un timeseries.');
                    end
                catch ME
                    disp(['Errore nell''accesso a v2: ' ME.message]);
                end
            else
                disp('Variabile out non trovata nel workspace.');
            end


    end
end


    function stopSimulation(app) % Stop the simulation (if needed)

        set_param(app.simulinkModel, 'SimulationCommand', 'stop');

        % Stop and delete timer
        if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
            stop(app.updateTimer);
            delete(app.updateTimer);
            app.updateTimer = [];
        end

        % delete plot
        cla(app.UIAxes);
        app.SIMULATIONTIMETextArea.Value = {'0 s'};
    end

        function closeApp(app, src, event)
        % Stop and delete timer
        if ~isempty(app.updateTimer) && isvalid(app.updateTimer)
            stop(app.updateTimer);
            delete(app.updateTimer);
        end
        delete(src);  % Closes the UIFigure
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTSIMULATIONButton
        function STARTSIMULATIONButtonPushed(app, event)

            modello = app.simulinkModel;
            % Load the model if not already open
            if ~bdIsLoaded(modello)
                load_system(modello);
            end

            startSimulation(app);

            % startSimulation(app);
            % Esegui la simulazione e salva i risultati

            %set_param(modello, 'SimulationCommand', 'start');

            
            %Inizializza i dati e avvia il timer già definito
            % app.e1_History = [];
            % app.e2_History = [];
            % app.time_History = [];
            % app.startTime = tic;
            % start(app.updateTimer);

            %tic 

            % results = sim(app.simulinkModel, 'StopTime', num2str(app.simulationDuration));

            % while toc<app.simulationDuration
            % 
            %     app.SIMULATIONTIMETextArea.Value = num2str(toc);
            %     pause(0.1)
            % end
            % 
            % app.SIMULATIONTIMETextArea.Value = '  ';
            % %app.SIMULATIONTIMETextArea.Value = num2str(app.simulationDuration);

            % muxOutput = results.get('out'); % Ottieni il dataset            


            % selected_option = app.SimulationButtonGroup.SelectedObject.Text;
            % switch selected_option
            %     case 'Rest'
            %         e1_low = muxOutput.getElement('e1').Values.Data;
            %         e2_low = muxOutput.getElement('e2').Values.Data;
            %         e1_low_per = prctile(e1_low, 95);
            %         e2_low_per = prctile(e2_low, 95);
            %         app.Threshold1lowTextArea.Value = num2str(e1_low_per);
            %         app.Threshold2lowTextArea.Value = num2str(e2_low_per);
            %         T1_low = str2double(app.Threshold1lowTextArea.Value);
            %         T2_low = str2double(app.Threshold2lowTextArea.Value);
            %         assignin('base', 'T1_low',T1_low );
            %         assignin('base', 'T2_low',T2_low );
            % 
            %      case 'Close'
            %         e1_high = muxOutput.getElement('e1').Values.Data;
            %         e1_high_per = prctile(e1_high, 95);
            %         app.Threshold1highTextArea.Value = num2str(e1_high_per);
            %         T1_high = str2double(app.Threshold1highTextArea.Value);
            %         assignin('base', 'T1_high', T1_high);
            % 
            %     case 'Open'
            %         e2_high = muxOutput.getElement('e2').Values.Data;
            %         e2_high_per = prctile(e2_high, 95);
            %         app.Threshold2highTextArea.Value = num2str(e2_high_per);
            %         T2_high = str2double(app.Threshold2highTextArea.Value);
            %         assignin('base', 'T2_high', T2_high);
            % 
            %     case 'Co-contraction'
            %         em = muxOutput.getElement('em').Values.Data;
            %         em_per_h = prctile(em, 50);
            %         em_per_l = prctile(em,5);
            %         app.CCThresholdhighTextArea.Value = num2str(em_per_h);
            %         app.CCThresholdlowTextArea.Value = num2str(em_per_l);
            % 
            %     case 'Slow Close'
            %         t1_slow = muxOutput.getElement('v1').Values.Data;
            %         t1_slow_per = prctile(t1_slow,95);
            %         app.Threshold1SlowTextArea.Value = num2str(t1_slow_per);
            % 
            %     case 'Fast Close'
            %         t1_fast = muxOutput.getElement('v1').Values.Data;
            %         t1_fast_per = prctile(t1_fast,95);
            %         app.Threshold1FastTextArea.Value = num2str(t1_fast_per);
            % 
            %     case 'Slow Open'
            %         t2_slow = muxOutput.getElement('v2').Values.Data;
            %         t2_slow_per = prctile(t2_slow,95);
            %         app.Threshold2SlowTextArea.Value = num2str(t2_slow_per);
            % 
            %     case 'Fast Open'
            %         t2_fast = muxOutput.getElement('v2').Values.Data;
            %         t2_fast_per = prctile(t2_fast,95);
            %         app.Threshold2FastTextArea.Value = num2str(t2_fast_per);


        end

        % Selection changed function: SimulationButtonGroup
        function SimulationButtonGroupSelectionChanged(app, event)
            % 
            % selectedButton = app.SimulationButtonGroup.SelectedObject;
            % fprintf('Selected simulation: %s\n', selectedButton.Text);
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.Threshold1lowTextArea.Value = ' ';
            app.Threshold2lowTextArea.Value = ' ';
            app.Threshold2highTextArea.Value = ' ';
            app.Threshold1highTextArea.Value = ' ';
            app.CCThresholdhighTextArea.Value = ' ';
            app.CCThresholdlowTextArea.Value = ' ';
            app.Threshold1SlowTextArea.Value = ' ';
            app.Threshold1FastTextArea.Value = ' ';
            app.Threshold2SlowTextArea.Value = ' ';
            app.Threshold2FastTextArea.Value = ' ';
            app.NAMEEditField.Value = '  ';
            app.SIMULATIONTIMETextArea.Value = '  ';

            assignin('base', 'T1_high', 0);
            assignin('base', 'T1_low', 0);
            assignin('base', 'T2_high', 0);
            assignin('base', 'T2_low', 0);
        end

        % Button pushed function: INITIALIZEButton
        function INITIALIZEButtonPushed(app, event)
            assignin('base', 'T1_high', 0);
            assignin('base', 'T1_low', 0);
            assignin('base', 'T2_high', 0);
            assignin('base', 'T2_low', 0);

            
            app.HH1 = plot(app.UIAxes, nan, 'r', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            app.HH2 = plot(app.UIAxes, nan, 'b', 'LineWidth', 1.5);
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Signals');
            title(app.UIAxes, 'Real time EMG signals');
            legend(app.UIAxes, {'EMG_1', 'EMG_2'});
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [0 20])
            ylim(app.UIAxes, [0 1100])
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            % HH1 = plot(nan)
            % hold on
            % HH2 = plot(nan)
            % xlabel(app.UIAxes, 'Time (s)');
            % ylabel(app.UIAxes, 'Signals');
            % title(app.UIAxes, 'Real time EMG signals');
            % legend(app.UIAxes, {'e1', 'e2'});
            % grid(app.UIAxes, 'on');
            % xlim([0 20])
            % ylim([0 1100])
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.UIFigure.CloseRequestFcn = @(src, event) closeApp(app, src, event);
            
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)

            patientName = app.NAMEEditField.Value;
             if iscell(patientName)
                patientName = strjoin(patientName, ' ');
            end

            if isempty(patientName)
                uialert(app.UIFigure, 'Insert Patient Name', 'Error');
                return;
            end
          
            folderName = 'soglie_pazienti';
            if ~exist(folderName, 'dir')
                mkdir(folderName);
            end

            fileName = fullfile(folderName, sprintf('%s.csv', patientName));
            %fileName =  sprintf('%s.txt', patientName);
           

            %Open the file in write mode;  if it already exists, it will be overwritten
            fileID = fopen(fileName, 'w');
            if fileID == -1
                uialert(app.UIFigure, 'Error in File opening', 'Error');
                return;
            end

            % Read thresholds from text areas
            convertCell = @(x) (iscell(x) && ~isempty(x)) * x{1} + (~iscell(x)) * x;
            
            % if iscell(x), x = x{1}; end

            % read the thresholds and convert into strings
            T1_low  = app.Threshold1lowTextArea.Value; if iscell(T1_low), T1_low = T1_low{1}; end
            T2_low  = app.Threshold2lowTextArea.Value; if iscell(T2_low), T2_low = T2_low{1}; end
            T1_high = app.Threshold1highTextArea.Value; if iscell(T1_high), T1_high = T1_high{1}; end
            T2_high = app.Threshold2highTextArea.Value; if iscell(T2_high), T2_high = T2_high{1}; end
            CC_high = app.CCThresholdhighTextArea.Value; if iscell(CC_high), CC_high = CC_high{1}; end
            CC_low  = app.CCThresholdlowTextArea.Value; if iscell(CC_low), CC_low = CC_low{1}; end
            T1_slow = app.Threshold1SlowTextArea.Value; if iscell(T1_slow), T1_slow = T1_slow{1}; end
            T1_fast = app.Threshold1FastTextArea.Value; if iscell(T1_fast), T1_fast = T1_fast{1}; end
            T2_slow = app.Threshold2SlowTextArea.Value; if iscell(T2_slow), T2_slow = T2_slow{1}; end

            T2_fast = app.Threshold2FastTextArea.Value; if iscell(T2_fast), T2_fast = T2_fast{1}; end
            % Write in text file
            fprintf(fileID, 'Patient Name: %s\n', patientName);
            fprintf(fileID, 'Threshold 1 low: %s\n', T1_low);
            fprintf(fileID, 'Threshold 2 low: %s\n', T2_low);
            fprintf(fileID, 'Threshold 1 high: %s\n', T1_high);
            fprintf(fileID, 'Threshold 2 high: %s\n', T2_high);
            fprintf(fileID, 'C-C Threshold high: %s\n', CC_high);
            fprintf(fileID, 'C-C Threshold low: %s\n', CC_low);
            fprintf(fileID, 'Threshold 1 Slow: %s\n', T1_slow);
            fprintf(fileID, 'Threshold 1 Fast: %s\n', T1_fast);
            fprintf(fileID, 'Threshold 2 Slow: %s\n', T2_slow);
            fprintf(fileID, 'Threshold 2 Fast: %s\n', T2_fast);

            fclose(fileID);
            uialert(app.UIFigure, sprintf('Data saved in %s', fileName), 'Saving');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SimulationButtonGroup = uibuttongroup(app.UIFigure);
            app.SimulationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationButtonGroupSelectionChanged, true);
            app.SimulationButtonGroup.Title = 'Simulation';
            app.RestButton = uiradiobutton(app.SimulationButtonGroup);
            app.RestButton.Text = 'Rest';
            app.RestButton.Value = true;
            app.OpenButton = uiradiobutton(app.SimulationButtonGroup);
            app.OpenButton.Text = 'Open';
            app.CloseButton = uiradiobutton(app.SimulationButtonGroup);
            app.CloseButton.Text = 'Close';
            app.CocontractionButton = uiradiobutton(app.SimulationButtonGroup);
            app.CocontractionButton.Text = 'Co-contraction';
            app.SlowCloseButton = uiradiobutton(app.SimulationButtonGroup);
            app.SlowCloseButton.Text = 'Slow Close';
            app.FastCloseButton = uiradiobutton(app.SimulationButtonGroup);
            app.FastCloseButton.Text = 'Fast Close';
            app.SlowOpenButton = uiradiobutton(app.SimulationButtonGroup);
            app.SlowOpenButton.Text = 'Slow Open';
            app.FastOpenButton = uiradiobutton(app.SimulationButtonGroup);
            app.FastOpenButton.Text = 'Fast Open';
            app.STARTSIMULATIONButton = uibutton(app.UIFigure, 'push');
            app.STARTSIMULATIONButton.ButtonPushedFcn = createCallbackFcn(app, @STARTSIMULATIONButtonPushed, true);
            app.STARTSIMULATIONButton.Text = 'START SIMULATION';
            app.Threshold1highTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold1highTextAreaLabel.Text = 'Threshold 1 high';
            app.Threshold1highTextArea = uitextarea(app.UIFigure);
            app.Threshold1lowTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold1lowTextAreaLabel.Text = 'Threshold 1 low';
            app.Threshold1lowTextArea = uitextarea(app.UIFigure);
            app.Threshold2highTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold2highTextAreaLabel.Text = 'Threshold 2 high';
            app.Threshold2highTextArea = uitextarea(app.UIFigure);
            app.Threshold2lowTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold2lowTextAreaLabel.Text = 'Threshold 2 low';
            app.Threshold2lowTextArea = uitextarea(app.UIFigure);
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.CCThresholdhighLabel = uilabel(app.UIFigure);
            app.CCThresholdhighLabel.Text = 'C-C Threshold% high';
            app.CCThresholdhighTextArea = uitextarea(app.UIFigure);
            app.INITIALIZEButton = uibutton(app.UIFigure, 'push');
            app.INITIALIZEButton.ButtonPushedFcn = createCallbackFcn(app, @INITIALIZEButtonPushed, true);
            app.INITIALIZEButton.Text = 'INITIALIZE';
            app.CCThresholdlowLabel = uilabel(app.UIFigure);
            app.CCThresholdlowLabel.Text = 'C-C Threshold% low';
            app.CCThresholdlowTextArea = uitextarea(app.UIFigure);
            app.Threshold1SlowTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold1SlowTextAreaLabel.Text = 'Threshold 1 Slow';
            app.Threshold1SlowTextArea = uitextarea(app.UIFigure);
            app.Threshold2SlowTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold2SlowTextAreaLabel.Text = 'Threshold 2 Slow';
            app.Threshold2SlowTextArea = uitextarea(app.UIFigure);
            app.Threshold1FastTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold1FastTextAreaLabel.Text = 'Threshold 1 Fast';
            app.Threshold1FastTextArea = uitextarea(app.UIFigure);
            app.Threshold2FastTextAreaLabel = uilabel(app.UIFigure);
            app.Threshold2FastTextAreaLabel.Text = 'Threshold 2 Fast';
            app.Threshold2FastTextArea = uitextarea(app.UIFigure);
            app.SIMULATIONTIMETextAreaLabel = uilabel(app.UIFigure);
            app.SIMULATIONTIMETextAreaLabel.Text = 'SIMULATION TIME';
            app.SIMULATIONTIMETextArea = uitextarea(app.UIFigure);
            app.NAMEEditFieldLabel = uilabel(app.UIFigure);
            app.NAMEEditFieldLabel.Text = 'NAME';
            app.NAMEEditField = uieditfield(app.UIFigure, 'text');
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Calibrazione_EMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end